classdef change_k_app_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        plot_velocity            matlab.ui.control.UIAxes
        K_footLabel              matlab.ui.control.Label
        K_footSpinner            matlab.ui.control.Spinner
        Gauge                    matlab.ui.control.SemicircularGauge
        show_model               matlab.ui.control.UIAxes
        FootHardStopSwitchLabel  matlab.ui.control.Label
        FootHardStopSwitch       matlab.ui.control.ToggleSwitch
        plot_position            matlab.ui.control.UIAxes
        FootDampingSwitchLabel   matlab.ui.control.Label
        FootDampingSwitch        matlab.ui.control.ToggleSwitch
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            global switch_val
            switch_val = 0;
            disp(switch_val)
            value = app.K_footSpinner.Value;
            app.Gauge.Value = value;
            param = parameters(value);
            [dynamics] = simulate(param, switch_val);
            
            time_max = max(dynamics(:,1));
            x = [0 time_max];
            y_vel = [0 0];
            y_pos = [0 0];
            y_pos_hs = [-param.hard_stop -param.hard_stop];

            hold(app.plot_position,'on');
            cla(app.plot_position)
            plot(app.plot_position,dynamics(:,1),dynamics(:,4),'LineWidth', 4);
            plot(app.plot_position,x,y_pos, 'LineStyle', '--', 'Color', 'k')
            if switch_val == 1
                plot(app.plot_position,x,y_pos_hs, 'LineStyle', '--', 'Color', 'k')
            end
            hold(app.plot_position,'off');
            
            hold(app.plot_velocity,'on');
            cla(app.plot_velocity)
            plot(app.plot_velocity,dynamics(:,1),dynamics(:,5),'LineWidth', 4);
            plot(app.plot_velocity,x,y_vel, 'LineStyle', '--', 'Color', 'k')
            hold(app.plot_velocity,'off');
            
            if switch_val == 1
                pic = imread('model_hs.png');
            elseif switch_val== 0
                pic = imread('model_no_hs.png');
            end
            hold(app.show_model,'on');
            imshow(pic, 'parent', app.show_model)
            axis(app.show_model,'off')
            hold(app.show_model,'off');

        end

        % Value changed function: K_footSpinner
        function K_footSpinnerValueChanged(app, event)
            clc;
            global switch_val damp_switch_val
            value = app.K_footSpinner.Value;
            app.Gauge.Value = value;
            param = parameters(value);
            
            
            disp('damp_switch_val')
            disp(damp_switch_val)
            if damp_switch_val == 1
                param.c_leg = 10;
                param.c_foot = 35;
            end
            
            [dynamics] = simulate(param, switch_val);
            
            time_max = max(dynamics(:,1));
            x = [0 time_max];
            y_vel = [0 0];
            y_pos = [0 0];
            y_pos_hs = [-param.hard_stop -param.hard_stop];

            hold(app.plot_position,'on');
            cla(app.plot_position)
            plot(app.plot_position,dynamics(:,1),dynamics(:,4),'LineWidth', 4);
            plot(app.plot_position,x,y_pos, 'LineStyle', '--', 'Color', 'k')
            if switch_val == 1
                plot(app.plot_position,x,y_pos_hs, 'LineStyle', '--', 'Color', 'k')
            end
            hold(app.plot_position,'off');
            
            hold(app.plot_velocity,'on');
            cla(app.plot_velocity)
            plot(app.plot_velocity,dynamics(:,1),dynamics(:,5),'LineWidth', 4);
            plot(app.plot_velocity,x,y_vel, 'LineStyle', '--', 'Color', 'k')
            hold(app.plot_velocity,'off');
        end

        % Value changed function: FootHardStopSwitch
        function FootHardStopSwitchValueChanged(app, event)
            global switch_val damp_switch_val
            value = app.FootHardStopSwitch.Value;
            k_value = app.K_footSpinner.Value;
            app.Gauge.Value = k_value;
            param = parameters(k_value);
            switch_val = strcmp(value, 'On');
            disp(switch_val)
            
            if damp_switch_val == 1
                param.c_leg = 10;
                param.c_foot = 35;
            end
            
            [dynamics] = simulate(param, switch_val);
            
            time_max = max(dynamics(:,1));
            x = [0 time_max];
            y_vel = [0 0];
            y_pos = [0 0];
            y_pos_hs = [-param.hard_stop -param.hard_stop];

            hold(app.plot_position,'on');
            cla(app.plot_position)
            plot(app.plot_position,dynamics(:,1),dynamics(:,4),'LineWidth', 4);
            plot(app.plot_position,x,y_pos, 'LineStyle', '--', 'Color', 'k')
            if switch_val == 1
                plot(app.plot_position,x,y_pos_hs, 'LineStyle', '--', 'Color', 'k')
            end
            hold(app.plot_position,'off');
            
            hold(app.plot_velocity,'on');
            cla(app.plot_velocity)
            plot(app.plot_velocity,dynamics(:,1),dynamics(:,5),'LineWidth', 4);
            plot(app.plot_velocity,x,y_vel, 'LineStyle', '--', 'Color', 'k')
            hold(app.plot_velocity,'off');
            
            if switch_val == 1
                pic = imread('model_hs.png');
            elseif switch_val== 0
                pic = imread('model_no_hs.png');
            end
            hold(app.show_model,'on');
            imshow(pic, 'parent', app.show_model)
            axis(app.show_model,'off')
            hold(app.show_model,'off');
            
        end

        % Value changed function: FootDampingSwitch
        function FootDampingSwitchValueChanged(app, event)
            value = app.FootDampingSwitch.Value;
            
            global switch_val damp_switch_val
            value = app.FootDampingSwitch.Value;
            k_value = app.K_footSpinner.Value;
            app.Gauge.Value = k_value;
            param = parameters(k_value);
            damp_switch_val = strcmp(value, 'On');
            disp(damp_switch_val)
            
            if damp_switch_val == 1
                param.c_leg = 10;
                param.c_foot = 35;
            end

            
            [dynamics] = simulate(param, switch_val);
            
            time_max = max(dynamics(:,1));
            x = [0 time_max];
            y_vel = [0 0];
            y_pos = [0 0];
            y_pos_hs = [-param.hard_stop -param.hard_stop];

            hold(app.plot_position,'on');
            cla(app.plot_position)
            plot(app.plot_position,dynamics(:,1),dynamics(:,4),'LineWidth', 4);
            plot(app.plot_position,x,y_pos, 'LineStyle', '--', 'Color', 'k')
            if switch_val == 1
                plot(app.plot_position,x,y_pos_hs, 'LineStyle', '--', 'Color', 'k')
            end
            hold(app.plot_position,'off');
            
            hold(app.plot_velocity,'on');
            cla(app.plot_velocity)
            plot(app.plot_velocity,dynamics(:,1),dynamics(:,5),'LineWidth', 4);
            plot(app.plot_velocity,x,y_vel, 'LineStyle', '--', 'Color', 'k')
            hold(app.plot_velocity,'off');
            
            if damp_switch_val == 1
                pic = imread('model_hs_damp.png');
            elseif switch_val== 0
                pic = imread('model_no_hs.png');
            end
            hold(app.show_model,'on');
            imshow(pic, 'parent', app.show_model)
            axis(app.show_model,'off')
            hold(app.show_model,'off');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.plot_velocity = uiaxes(app.UIFigure);
            title(app.plot_velocity, '')
            xlabel(app.plot_velocity, 'Time (sec)')
            ylabel(app.plot_velocity, 'Velocity (m/s)')
            app.plot_velocity.XLim = [0 0.3];
            app.plot_velocity.YLim = [-2.5 0.6];
            app.K_footLabel = uilabel(app.UIFigure);
            app.K_footLabel.Text = 'K_foot';
            app.K_footSpinner = uispinner(app.UIFigure);
            app.K_footSpinner.Step = 200;
            app.K_footSpinner.Limits = [1000 4200];
            app.K_footSpinner.ValueChangedFcn = createCallbackFcn(app, @K_footSpinnerValueChanged, true);
            app.K_footSpinner.Value = 3000;
            app.Gauge = uigauge(app.UIFigure, 'semicircular');
            app.Gauge.Limits = [1000 4200];
            app.Gauge.MajorTicks = [1000 1800 2600 3400 4200];
            app.Gauge.MajorTickLabels = {'1000', '1800', '2600', '3400', '4200'};
            app.show_model = uiaxes(app.UIFigure);
            title(app.show_model, '')
            xlabel(app.show_model, '')
            ylabel(app.show_model, '')
            app.show_model.XAxisLocation = 'origin';
            app.FootHardStopSwitchLabel = uilabel(app.UIFigure);
            app.FootHardStopSwitchLabel.Text = 'Foot Hard Stop';
            app.FootHardStopSwitch = uiswitch(app.UIFigure, 'toggle');
            app.FootHardStopSwitch.ValueChangedFcn = createCallbackFcn(app, @FootHardStopSwitchValueChanged, true);
            app.plot_position = uiaxes(app.UIFigure);
            title(app.plot_position, 'Foot Response When Changing K_{foot}')
            xlabel(app.plot_position, '')
            ylabel(app.plot_position, 'Height (m)')
            app.plot_position.XLim = [0 0.3];
            app.plot_position.YLim = [-0.15 0.01];
            app.FootDampingSwitchLabel = uilabel(app.UIFigure);
            app.FootDampingSwitchLabel.Text = 'Foot Damping';
            app.FootDampingSwitch = uiswitch(app.UIFigure, 'toggle');
            app.FootDampingSwitch.ValueChangedFcn = createCallbackFcn(app, @FootDampingSwitchValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = change_k_app_2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end